classdef SpitLight600_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaserControlUIFigure            matlab.ui.Figure
        providedbyALG2024Label          matlab.ui.control.Label
        SingleShotPanel                 matlab.ui.container.Panel
        ShotButton                      matlab.ui.control.Button
        Switch2                         matlab.ui.control.Switch
        SpitLight600ControlLabel        matlab.ui.control.Label
        PockelsCellSettingsPanel        matlab.ui.container.Panel
        AdvancedModeSwitch              matlab.ui.control.Switch
        HV2VEditField                   matlab.ui.control.NumericEditField
        HV2VEditFieldLabel              matlab.ui.control.Label
        Slider_5                        matlab.ui.control.Slider
        Slider_4                        matlab.ui.control.Slider
        HV1VEditField                   matlab.ui.control.NumericEditField
        HV1VEditFieldLabel              matlab.ui.control.Label
        DoublePulseSettingsPanel        matlab.ui.container.Panel
        Slider_3                        matlab.ui.control.Slider
        DoublePulseDelaynsEditField     matlab.ui.control.NumericEditField
        DoublePulseDelaynsEditFieldLabel  matlab.ui.control.Label
        RepetitionRateandPulseEnergyPanel  matlab.ui.container.Panel
        Gauge                           matlab.ui.control.Gauge
        Slider_2                        matlab.ui.control.Slider
        PumpDelaysEditField             matlab.ui.control.NumericEditField
        PumpDelaysLabel                 matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        PulseRepetitionRateHzEditField  matlab.ui.control.NumericEditField
        PulseRepetitionRateHzEditFieldLabel  matlab.ui.control.Label
        SerialConnectiontoTriggerboxControllerArduinoPanel  matlab.ui.container.Panel
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        SelectComPortDropDown           matlab.ui.control.DropDown
        SelectComPortLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        singleShot = 0; % Description
        shot = 0;
        pumpDelay = 150;
        pulseDivider = 20; % -> 1 Hz
        doublePulseDelay = 100;
        hv1 = 0.0;
        hv2 = 4.5;
        % s = serialport("COM1");
        s;
        freeports;
    end
    
    methods (Access = private)
        
        function sendDataToController(app)
            msg = ['<',...
                   num2str(app.singleShot) ','...
                   num2str(app.shot) ','... 
                   num2str(app.pumpDelay) ','...
                   num2str(app.pulseDivider) ','...
                   num2str(app.doublePulseDelay) ','...
                   num2str(app.hv1) ','...
                   num2str(app.hv2) ','...
                   '>'];
            % app.StatusLamp.Color = rand(1,3);
            app.s.writeline(msg)
            %disp(msg);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: SelectComPortDropDown
        function SelectComPortDropDownOpening(app, event)
            % List all available Com Ports
            app.freeports = serialportlist("available");
            app.SelectComPortDropDown.Items = app.freeports;

        end

        % Clicked callback: SelectComPortDropDown
        function SelectComPortDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            % disp(app.freeports{item})
            % app.s = serialport(app.freeports{item},9600);
            if item ~= 0
                % try to connect to COM port
                try
                    app.s = serialport(app.freeports{item},9600);
                    
                catch
                    uialert(app.LaserControlUIFigure,['Port ' app.freeports{item} ' not available'] ,"Invalid COM Port");
                    return
                end   
                % Enable all Controls
                app.StatusLamp.Color = [0 1 0];
                app.PulseRepetitionRateHzEditFieldLabel.Enable = 'on';
                app.PulseRepetitionRateHzEditField.Enable = "on";
                app.Slider.Enable = "on";
                app.Gauge.Enable = "on";
                app.PumpDelaysEditField.Enable = "on";
                app.PumpDelaysLabel.Enable = "on";
                app.Slider_2.Enable = "on";
                app.DoublePulseDelaynsEditField.Enable = "on";
                app.DoublePulseDelaynsEditFieldLabel.Enable = "on";
                app.Slider_3.Enable = "on";
                
                app.AdvancedModeSwitch.Enable = "on";
                % app.HV1VEditField.Enable = "on";
                % app.HV1VEditFieldLabel.Enable = "on";
                % app.Slider_4.Enable = "on";
                % app.HV2VEditField.Enable = "on";
                % app.HV2VEditFieldLabel.Enable = "on";
                % app.Slider_5.Enable = "on";
                app.Switch2.Enable = "on";
                % app.ShotButton.Enable = "on";
                app.SelectComPortDropDown.Enable = "off";

                % set HV Values
                app.HV1VEditField.Value = app.hv1;
                app.Slider_4.Value = app.hv1;
                app.HV2VEditField.Value = app.hv2;
                app.Slider_5.Value = app.hv2;
                
            end
        end

        % Close request function: LaserControlUIFigure
        function LaserControlUIFigureCloseRequest(app, event)
            % fclose(app.s);
            delete(app.s);
            delete(app)
            
        end

        % Value changed function: SelectComPortDropDown
        function SelectComPortDropDownValueChanged(app, event)
           
        end

        % Value changed function: AdvancedModeSwitch
        function AdvancedModeSwitchValueChanged(app, event)
            value = app.AdvancedModeSwitch.Value;
            switch value
                case 'Simple'
                    app.HV1VEditField.Enable = "off";
                    app.HV1VEditFieldLabel.Enable = "off";
                    app.Slider_4.Enable = "off";
                    app.HV2VEditField.Enable = "off";
                    app.HV2VEditFieldLabel.Enable = "off";
                    app.Slider_5.Enable = "off";

                    app.hv1 = 0.0;
                    app.hv2 = 4.5;

                    app.HV1VEditField.Value = app.hv1;
                    app.Slider_4.Value = app.hv1;
                    app.HV2VEditField.Value = app.hv2;
                    app.Slider_5.Value = app.hv2;

                
                case 'Advanced'
                    message = ["Fire hazard!","HV 1 Stage might draw too much current. Watch current consumption and temperature!"];
                    uialert(app.LaserControlUIFigure,message,"Warning", ...
                    "Icon","warning");
                    app.HV1VEditField.Enable = "on";
                    app.HV1VEditFieldLabel.Enable = "on";
                    app.Slider_4.Enable = "on";
                    app.HV2VEditField.Enable = "on";
                    app.HV2VEditFieldLabel.Enable = "on";
                    app.Slider_5.Enable = "on";

                    app.hv1 = 0.0;
                    app.hv2 = 0.0;

                    app.HV1VEditField.Value = app.hv1;
                    app.Slider_4.Value = app.hv1;
                    app.HV2VEditField.Value = app.hv2;
                    app.Slider_5.Value = app.hv2;
            end
            sendDataToController(app);
                    
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
            switch value
                case 'Off'
                    app.ShotButton.Enable = "off";
                    app.PulseRepetitionRateHzEditFieldLabel.Enable = 'on';
                    app.PulseRepetitionRateHzEditField.Enable = "on";
                    app.Slider.Enable = "on";
                    app.singleShot = 0;
                
                case 'On'
                    app.ShotButton.Enable = "on";
                    app.PulseRepetitionRateHzEditFieldLabel.Enable = 'off';
                    app.PulseRepetitionRateHzEditField.Enable = "off";
                    app.Slider.Enable = "off";
                    app.singleShot = 1;
                otherwise
                    app.ShotButton.Enable = "off";
            end
            sendDataToController(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            value = round(value);
            app.pulseDivider = 21 - value; % <-- 20 -(value-1)
            app.PulseRepetitionRateHzEditField.Value = value;
            sendDataToController(app);
            
        end

        % Value changed function: PulseRepetitionRateHzEditField
        function PulseRepetitionRateHzEditFieldValueChanged(app, event)
            value = app.PulseRepetitionRateHzEditField.Value;
            value = round(value);
            app.pulseDivider = 21 - value; % <-- 20 -(value-1);
            app.Slider.Value = value;
            sendDataToController(app);            
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            value = round(value);
            app.pumpDelay = value;
            app.PumpDelaysEditField.Value = value;
            app.Gauge.Value = round((value-150)/(215-150)*100);
            sendDataToController(app);
        end

        % Value changed function: PumpDelaysEditField
        function PumpDelaysEditFieldValueChanged(app, event)
            value = app.PumpDelaysEditField.Value;
            value = round(value);
            app.pumpDelay = value;
            app.Slider_2.Value = value;
            app.Gauge.Value = round((value-150)/(215-150)*100);
            sendDataToController(app);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            value = round(value);
            app.doublePulseDelay = value;
            app.DoublePulseDelaynsEditField.Value = value;
            sendDataToController(app);            
        end

        % Value changed function: DoublePulseDelaynsEditField
        function DoublePulseDelaynsEditFieldValueChanged(app, event)
            value = app.DoublePulseDelaynsEditField.Value;
            value = round(value);
            app.doublePulseDelay = value;
            app.Slider_3.Value = value;
            sendDataToController(app); 
        end

        % Value changed function: HV1VEditField
        function HV1VEditFieldValueChanged(app, event)
            value = app.HV1VEditField.Value;
            value = round(value,2);
            app.hv1 = value;
            app.Slider_4.Value = value;
            sendDataToController(app);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            value = round(value,2);
            app.hv1 = value;
            app.HV1VEditField.Value = value;
            sendDataToController(app);
        end

        % Value changed function: HV2VEditField
        function HV2VEditFieldValueChanged(app, event)
            value = app.HV2VEditField.Value;
            value = round(value,2);
            app.hv2 = value;
            app.Slider_5.Value = value;
            sendDataToController(app);
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            value = round(value,2);
            app.hv2 = value;
            app.HV2VEditField.Value = value;
            sendDataToController(app);
        end

        % Button pushed function: ShotButton
        function ShotButtonPushed(app, event)
            app.shot = 1;
            sendDataToController(app);
            app.shot = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaserControlUIFigure = uifigure('Visible', 'off');
            app.LaserControlUIFigure.Name = 'Laser Control';
            app.LaserControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @LaserControlUIFigureCloseRequest, true);
            app.SerialConnectiontoTriggerboxControllerArduinoPanel = uipanel(app.LaserControlUIFigure);
            app.SerialConnectiontoTriggerboxControllerArduinoPanel.Title = 'Serial Connection to Triggerbox Controller (Arduino)';
            app.SelectComPortLabel = uilabel(app.SerialConnectiontoTriggerboxControllerArduinoPanel);
            app.SelectComPortLabel.Text = 'Select Com Port:';
            app.SelectComPortDropDown = uidropdown(app.SerialConnectiontoTriggerboxControllerArduinoPanel);
            app.SelectComPortDropDown.Items = {'-'};
            app.SelectComPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectComPortDropDownOpening, true);
            app.SelectComPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectComPortDropDownValueChanged, true);
            app.SelectComPortDropDown.ClickedFcn = createCallbackFcn(app, @SelectComPortDropDownClicked, true);
            app.SelectComPortDropDown.Value = '-';
            app.StatusLampLabel = uilabel(app.SerialConnectiontoTriggerboxControllerArduinoPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.SerialConnectiontoTriggerboxControllerArduinoPanel);
            app.RepetitionRateandPulseEnergyPanel = uipanel(app.LaserControlUIFigure);
            app.RepetitionRateandPulseEnergyPanel.Title = 'Repetition Rate and Pulse Energy';
            app.PulseRepetitionRateHzEditFieldLabel = uilabel(app.RepetitionRateandPulseEnergyPanel);
            app.PulseRepetitionRateHzEditFieldLabel.Enable = 'off';
            app.PulseRepetitionRateHzEditFieldLabel.Text = 'Pulse Repetition Rate (Hz):';
            app.PulseRepetitionRateHzEditField = uieditfield(app.RepetitionRateandPulseEnergyPanel, 'numeric');
            app.PulseRepetitionRateHzEditField.Limits = [1 20];
            app.PulseRepetitionRateHzEditField.ValueDisplayFormat = '%.0f';
            app.PulseRepetitionRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @PulseRepetitionRateHzEditFieldValueChanged, true);
            app.PulseRepetitionRateHzEditField.Enable = 'off';
            app.PulseRepetitionRateHzEditField.Value = 1;
            app.Slider = uislider(app.RepetitionRateandPulseEnergyPanel);
            app.Slider.Limits = [1 20];
            app.Slider.MajorTicks = [1 5 10 15 20];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.Slider.Enable = 'off';
            app.Slider.Value = 1;
            app.PumpDelaysLabel = uilabel(app.RepetitionRateandPulseEnergyPanel);
            app.PumpDelaysLabel.Enable = 'off';
            app.PumpDelaysLabel.Text = 'Pump Delay (µs):';
            app.PumpDelaysEditField = uieditfield(app.RepetitionRateandPulseEnergyPanel, 'numeric');
            app.PumpDelaysEditField.Limits = [150 215];
            app.PumpDelaysEditField.ValueDisplayFormat = '%.0f';
            app.PumpDelaysEditField.ValueChangedFcn = createCallbackFcn(app, @PumpDelaysEditFieldValueChanged, true);
            app.PumpDelaysEditField.Enable = 'off';
            app.PumpDelaysEditField.Value = 150;
            app.Slider_2 = uislider(app.RepetitionRateandPulseEnergyPanel);
            app.Slider_2.Limits = [150 215];
            app.Slider_2.MajorTicks = [155 170 185 200 215];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Enable = 'off';
            app.Slider_2.Value = 150;
            app.Gauge = uigauge(app.RepetitionRateandPulseEnergyPanel, 'circular');
            app.Gauge.Enable = 'off';
            app.DoublePulseSettingsPanel = uipanel(app.LaserControlUIFigure);
            app.DoublePulseSettingsPanel.Title = 'Double Pulse Settings';
            app.DoublePulseDelaynsEditFieldLabel = uilabel(app.DoublePulseSettingsPanel);
            app.DoublePulseDelaynsEditFieldLabel.Enable = 'off';
            app.DoublePulseDelaynsEditFieldLabel.Text = 'Double Pulse Delay (ns):';
            app.DoublePulseDelaynsEditField = uieditfield(app.DoublePulseSettingsPanel, 'numeric');
            app.DoublePulseDelaynsEditField.ValueChangedFcn = createCallbackFcn(app, @DoublePulseDelaynsEditFieldValueChanged, true);
            app.DoublePulseDelaynsEditField.Enable = 'off';
            app.Slider_3 = uislider(app.DoublePulseSettingsPanel);
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Enable = 'off';
            app.PockelsCellSettingsPanel = uipanel(app.LaserControlUIFigure);
            app.PockelsCellSettingsPanel.Title = 'Pockels Cell Settings';
            app.HV1VEditFieldLabel = uilabel(app.PockelsCellSettingsPanel);
            app.HV1VEditFieldLabel.Enable = 'off';
            app.HV1VEditFieldLabel.Text = 'HV 1 (V):';
            app.HV1VEditField = uieditfield(app.PockelsCellSettingsPanel, 'numeric');
            app.HV1VEditField.Limits = [0 4.5];
            app.HV1VEditField.ValueChangedFcn = createCallbackFcn(app, @HV1VEditFieldValueChanged, true);
            app.HV1VEditField.Enable = 'off';
            app.Slider_4 = uislider(app.PockelsCellSettingsPanel);
            app.Slider_4.Limits = [0 4.5];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.Enable = 'off';
            app.Slider_5 = uislider(app.PockelsCellSettingsPanel);
            app.Slider_5.Limits = [0 4.5];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.Enable = 'off';
            app.HV2VEditFieldLabel = uilabel(app.PockelsCellSettingsPanel);
            app.HV2VEditFieldLabel.Enable = 'off';
            app.HV2VEditFieldLabel.Text = 'HV 2 (V):';
            app.HV2VEditField = uieditfield(app.PockelsCellSettingsPanel, 'numeric');
            app.HV2VEditField.Limits = [0 4.5];
            app.HV2VEditField.ValueChangedFcn = createCallbackFcn(app, @HV2VEditFieldValueChanged, true);
            app.HV2VEditField.Enable = 'off';
            app.AdvancedModeSwitch = uiswitch(app.PockelsCellSettingsPanel, 'slider');
            app.AdvancedModeSwitch.Items = {'Simple', 'Advanced'};
            app.AdvancedModeSwitch.ValueChangedFcn = createCallbackFcn(app, @AdvancedModeSwitchValueChanged, true);
            app.AdvancedModeSwitch.Enable = 'off';
            app.AdvancedModeSwitch.Value = 'Simple';
            app.SpitLight600ControlLabel = uilabel(app.LaserControlUIFigure);
            app.SpitLight600ControlLabel.Text = 'SpitLight 600 Control';
            app.SingleShotPanel = uipanel(app.LaserControlUIFigure);
            app.SingleShotPanel.Title = 'Single Shot';
            app.Switch2 = uiswitch(app.SingleShotPanel, 'slider');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Enable = 'off';
            app.ShotButton = uibutton(app.SingleShotPanel, 'push');
            app.ShotButton.ButtonPushedFcn = createCallbackFcn(app, @ShotButtonPushed, true);
            app.ShotButton.Enable = 'off';
            app.ShotButton.Text = 'Shot';
            app.providedbyALG2024Label = uilabel(app.LaserControlUIFigure);
            app.providedbyALG2024Label.Text = 'provided by ALG 2024';
            % Show the figure after all components are created
            app.LaserControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpitLight600_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LaserControlUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaserControlUIFigure)
        end
    end
end